classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        CaptureTab                     matlab.ui.container.Tab
        NormalizeButton                matlab.ui.control.Button
        MododenormalizacinDropDown     matlab.ui.control.DropDown
        MododenormalizacinDropDownLabel  matlab.ui.control.Label
        ListadesealesPanel             matlab.ui.container.Panel
        ListBox                        matlab.ui.control.ListBox
        ListBoxLabel                   matlab.ui.control.Label
        PlayButton                     matlab.ui.control.Button
        ImportarsealPanel              matlab.ui.container.Panel
        ImportButton                   matlab.ui.control.Button
        GenerarsealPanel               matlab.ui.container.Panel
        GenerarButton                  matlab.ui.control.Button
        TipodesealDropDown             matlab.ui.control.DropDown
        TipodesealDropDownLabel        matlab.ui.control.Label
        CapturasealPanel               matlab.ui.container.Panel
        DAQButton                      matlab.ui.control.Button
        GuardarButton                  matlab.ui.control.Button
        GrabarButton                   matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        FiltradoTab                    matlab.ui.container.Tab
        SeleccindelfiltroPanel         matlab.ui.container.Panel
        ReproducirsealfiltradaButton   matlab.ui.control.Button
        ReproducirsealdeentradaButton  matlab.ui.control.Button
        TamaodelaventanaSpinner        matlab.ui.control.Spinner
        TamaodelaventanaSpinnerLabel   matlab.ui.control.Label
        SealdeentradaDropDown          matlab.ui.control.DropDown
        SealdeentradaDropDownLabel     matlab.ui.control.Label
        CoeficientesFIRButton          matlab.ui.control.Button
        CoeficientesIIRButton          matlab.ui.control.Button
        FilterButtonGroup              matlab.ui.container.ButtonGroup
        IIR4Button                     matlab.ui.control.ToggleButton
        IIR3Button                     matlab.ui.control.ToggleButton
        IIR2Button                     matlab.ui.control.ToggleButton
        IIR1Button                     matlab.ui.control.ToggleButton
        FIR4Button                     matlab.ui.control.ToggleButton
        FIR3Button                     matlab.ui.control.ToggleButton
        FIR2Button                     matlab.ui.control.ToggleButton
        FIR1Button                     matlab.ui.control.ToggleButton
        FiltrarButton                  matlab.ui.control.Button
        GraficosdelasealPanel          matlab.ui.container.Panel
        UIAxes3_2                      matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        ProcesamientoTab               matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        EcoButton                      matlab.ui.control.StateButton
        Play1Button                    matlab.ui.control.Button
        Play2Button                    matlab.ui.control.Button
        ProcessedsignalButton          matlab.ui.control.Button
        CambiodefrecuenciaydesplazamientoentiempoPanel  matlab.ui.container.Panel
        Switch_2                       matlab.ui.control.Switch
        Switch                         matlab.ui.control.Switch
        HzEditField_4                  matlab.ui.control.EditField
        HzEditField_4Label             matlab.ui.control.Label
        sEditField                     matlab.ui.control.EditField
        sEditFieldLabel                matlab.ui.control.Label
        sEditField_2                   matlab.ui.control.EditField
        sEditField_2Label              matlab.ui.control.Label
        HzEditField                    matlab.ui.control.EditField
        HzEditFieldLabel               matlab.ui.control.Label
        ModificarButton_2              matlab.ui.control.Button
        ModificarButton                matlab.ui.control.Button
        GenerarsealapartirdeunaexistentePanel  matlab.ui.container.Panel
        EstreoButton                   matlab.ui.control.Button
        MonoButton                     matlab.ui.control.Button
        VolumePanel                    matlab.ui.container.Panel
        AplicarButton                  matlab.ui.control.Button
        ButtonGroup2                   matlab.ui.container.ButtonGroup
        AmplitudlinealmentedecrecienteButton  matlab.ui.control.RadioButton
        AmplitudlinealmentecrecienteButton  matlab.ui.control.RadioButton
        AmplitudconstanteButton        matlab.ui.control.RadioButton
        VolumeSlider                   matlab.ui.control.Slider
        VolumeSliderLabel              matlab.ui.control.Label
        OperacionesPanel               matlab.ui.container.Panel
        SumButton                      matlab.ui.control.Button
        MultiplyButton                 matlab.ui.control.Button
        SeleccindesealesPanel          matlab.ui.container.Panel
        Sealdeentrada2DropDown         matlab.ui.control.DropDown
        Sealdeentrada2DropDownLabel    matlab.ui.control.Label
        Sealdeentrada1DropDown         matlab.ui.control.DropDown
        Sealdeentrada1DropDownLabel    matlab.ui.control.Label
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3_3                      matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        DominiofrecuencialTab          matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        SpectrumTab                    matlab.ui.container.Tab
        UIAxes7_2                      matlab.ui.control.UIAxes
        UIAxes7                        matlab.ui.control.UIAxes
        Tab                            matlab.ui.container.Tab
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes6                        matlab.ui.control.UIAxes
        UIAxes5_2                      matlab.ui.control.UIAxes
        UIAxes5_3                      matlab.ui.control.UIAxes
        SeleccindesealesPanel_2        matlab.ui.container.Panel
        SpectrumButton                 matlab.ui.control.Button
        AplicarDFTButton_2             matlab.ui.control.Button
        AplicarDFTButton               matlab.ui.control.Button
        SistemasDropDown               matlab.ui.control.DropDown
        SistemasDropDownLabel          matlab.ui.control.Label
        SealesDropDown                 matlab.ui.control.DropDown
        SealesDropDownLabel            matlab.ui.control.Label
    end


    properties (Access = public)
        x_in % Señal de audio
        t_in % Instantes de tiempo en la graficación
        sample_rate % Frecuencia de muestreo
        valor % Opción seleccionada en el dropdown
        num_signals % Número de señales del dropdown y listbox
        num_sinteticas = 0; % Conteo para el nombre de archivos creados por generación
        num_grabaciones = 0; % Conteo para el nombre de archivos creados por micrófono
        num_importes = 0; % Conteo para el nombre de archivos creados por importación
        signal_1 % Señal seleccionada 1
        signal_2 % Señal seleccionada 2
        signal_3
        sample_rate_1 % Frecuencia de muestreo de la señal seleccionada 1
        sample_rate_2 % Frecuencia de muestreo de la señal seleccionada 2
        sample_rate_3
        valor_usuarioak % Almacena los coeficientes a(k) para el filtro IIR
        valor_usuariobk % Almacena los coeficientes b(k) para el filtro IIR
        valor_usuario % Almacena los coeficientes para el filtro FIR
        datain
        freqmuestreoin
        signals = {};
        filtrada
        muestreofiltrada
        data1in
        freqmuestreo1in
        data2in
        freqmuestreo2in
        signal
        filtradaFs
        DFTsignalin
        DFTsampleratein
        magnitudein
        phasein
        sample_rate_DAQ
        signal_DAQ
        time_DAQ
    end

    properties (Access = public)
        signalsData = {};  % Cell array to store signals and sample rates
    end

methods (Access = public)
    function [filsig, filsigFs] = convolucion(app, x, h, bloqsize, fs)
    Lx = length(x); % Longitud de la señal de entrada
    Lh = length(h); % Longitud del filtro
    Ly = Lx + Lh - 1; % Longitud de la salida

    y = zeros(1, Ly); % Inicializar la salida

    Lxb = bloqsize; % Tamaño de bloque
    Lyb = Lxb + Lh - 1; % Tamaño de salida del bloque

    % Primer bloque
    xb = x(1:Lxb);
    y(1:Lyb) = conv(h, xb); % Convolución del primer bloque

    % Número de bloques
    Nb = floor(Lx / Lxb);
    
    for k = 2:Nb
        % Obtener el bloque k
        xb = x(1 + (k - 1) * Lxb : Lxb * k); 
        
        % Convolución del bloque
        yb = conv(h, xb); 
        
        % Ajustar y_start y y_end para la asignación correcta
        y_start = 1 + (k - 1) * Lxb; % Este debe ser 1 + (k - 1) * Lxb para el bloque k
        y_end = y_start + length(yb) - 1; 


        % Verificar que y_end no exceda los límites de y
        if y_end <= length(y)
            y(y_start:y_end) = y(y_start:y_end) + yb; % Sumar a la salida
        else
            fprintf('Warning: y_end exceeds the length of y.\n');
        end
    end

    % Calcular la convolución para el bloque incompleto
    Lxr = mod(Lx, Lxb); 

    if Lxr ~= 0 % Si hay un bloque incompleto
        xbr = x(1 + Nb * Lxb : Lx); % Obtener el bloque incompleto
        ybr = conv(h, xbr); % Convolución del bloque incompleto
        
        % Ajustar y_start y y_end para el bloque incompleto
        y_start = 1 + Nb * Lxb; 
        y_end = Ly; % El final será la longitud de y

        
        % Verificar que y_end no exceda los límites de y
        if y_end <= length(y)
            y(y_start:y_end) = y(y_start:y_end) + ybr; % Sumar a la salida
        else
            fprintf('Warning: y_end exceeds the length of y.\n');
        end
    end 

    filsig = y; % Asignar la salida a filsig
    filsigFs = fs; % Guardar frecuencia de muestreo en filsigFs
end


        function [filsig, filsigFs] = ec_diference(~, x, a, b, fs)
    N = length(a);     % Orden N 
    M = length(b) - 1;   % Orden M

    % Señal de entrada x(n)
    Lx = length(x);
    
    filsig = zeros(1, Lx); % Asignar memoria a y(n)
    v = zeros(1, Lx);      % Asignar memoria a v(n)
    
    for n = 0:Lx - 1
        m = n + 1;

        % Calcular v(n) 
        temp_v = 0;
        for k = 0:M
            kk = k + 1;
            ind = n - k + 1;

            if ind >= 1 && ind < Lx
                temp_v = temp_v + b(kk) * x(ind);
            end
        end
        v(m) = temp_v;

        temp_y = 0;
        for k = 1:N
            ind = n - k + 1;

            if ind >= 1 && ind < Lx
                temp_y = temp_y + a(k) * filsig(ind);
            end
        end
        filsig(m) = temp_y + v(m); % Respuesta total
    end   

    filsigFs = fs; % Asignar la frecuencia de muestreo
end
        

end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GrabarButton
        function GrabarButtonPushed(app, event)
              [app.x_in, app.sample_rate] = grabar_pushed_button(app.UIAxes);
              % app.t_in = length(app.x_in)-1;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
             sound(app.x_in, app.sample_rate);
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            % Ingresar los parámetros para grabar el audio
            prompt = {'Nombre del archivo:'};
            dlgtitle = 'Guardar archivo';
            dims = [1 35];
            definput = {'archivo'};
            answer = inputdlg(prompt, dlgtitle, dims, definput);
            
            % Recuperar parámetros de la caja de diálogo
            nombre_archivo = (answer{1});

            nombre_archivo_completo = [nombre_archivo, '.wav'];
            disp(nombre_archivo_completo);
            audiowrite(nombre_archivo_completo, app.x_in, app.sample_rate);

            % Increment the signal count and add to the cell array
            app.num_signals = length(app.signalsData) + 1;
            app.signalsData{app.num_signals} = {app.x_in, app.sample_rate};

            % Add an entry to the ListBox to represent this signal
            app.num_grabaciones = app.num_grabaciones + 1;
            signalName = ['Grabación', num2str(app.num_grabaciones)];  % Name the signal (e.g., "Signal 1", "Signal 2")
            app.ListBox.Items{app.num_signals} = signalName;     % Add to the List Box
            app.SealdeentradaDropDown.Items{app.num_signals} = signalName;  %Add to the Input Signal Dropdown Button
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [filename, path] = uigetfile({'*.mp3;*.wav'});
            figure(app.UIFigure);
            fullFileName = fullfile(path, filename);  % Construye la ruta completa del archivo
            
            % Recuperar los datos importados
            [app.x_in, app.sample_rate] = audioread(fullFileName);
            % app.t_in = length(app.x_in)-1;
            % sound(data, sample_rate);

            % Graficar Canal 1 en UIAxes de App Designer
            plot(app.UIAxes, app.x_in(:,1), 'v');
            title(app.UIAxes, 'Señal Estereofónica: Canal 1');
            xlabel(app.UIAxes, 'n');
            grid(app.UIAxes, 'on');
            % add_to_signals(app, app.x_in, app.sample_rate,3);

            [app.x_in, app.sample_rate, ...
                app.ListBox.Items, ...
                app.num_signals, app.SealdeentradaDropDown.Items, ...
                app.Sealdeentrada1DropDown.Items, app.Sealdeentrada2DropDown.Items, app.signalsData, ...
                app.num_sinteticas, app.num_importes, ...
                app.num_grabaciones] = signal_count_in_list_box(app.signalsData, app.ListBox.Items, app.num_sinteticas, app.num_importes, app.num_grabaciones, ...
                app.x_in, app.sample_rate, 'importada', app.SealdeentradaDropDown.Items, app.Sealdeentrada1DropDown.Items, app.Sealdeentrada2DropDown.Items);
            

        end

        % Value changed function: TipodesealDropDown
        function TipodesealDropDownValueChanged(app, event)
            app.valor = app.TipodesealDropDown.Value;
        end

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            % Botón que genera las funciones del dropdown menu
            [app.x_in, app.t_in, app.sample_rate] = generar_funciones(app.UIAxes, app.valor);
            % [app.x_in] = preprocesamiento(app.x_in);

            [app.x_in, app.sample_rate, ...
                app.ListBox.Items, ...
                app.num_signals, app.SealdeentradaDropDown.Items, ...
                app.Sealdeentrada1DropDown.Items, app.Sealdeentrada2DropDown.Items, app.SealesDropDown.Items, app.signalsData, ...
                app.num_sinteticas, app.num_importes, ...
                app.num_grabaciones] = signal_count_in_list_box(app.signalsData, app.ListBox.Items, app.num_sinteticas, app.num_importes, app.num_grabaciones, ...
                app.x_in, app.sample_rate, 'sintetica', app.SealdeentradaDropDown.Items, app.Sealdeentrada1DropDown.Items, app.Sealdeentrada2DropDown.Items, app.SealesDropDown.Items);


        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            [app.ListBox.Items, app.ListBox.Value, app.x_in, app.sample_rate, number_of_points] = list_box(app.signalsData, app.ListBox.Items, app.ListBox.Value);
            % Now you can do something with the selected signal and sample rate, like playing it
            graficar(app.UIAxes, 'Selected signal', app.x_in);
            sound(app.x_in, app.sample_rate);

        end

        % Button pushed function: FiltrarButton
        function FiltrarButtonPushed(app, event)
            selectedButton = app.FilterButtonGroup.SelectedObject;
            filtroSeleccionado = selectedButton.Text;
            [app.filtrada, app.filtradaFs] = filtrar(app,app.signal, app.sample_rate, app.TamaodelaventanaSpinner.Value, filtroSeleccionado);
            graficar(app.UIAxes3_2,'Input signal', app.signal);
            graficar(app.UIAxes_3,'Filtered signal', app.filtrada);

        end

        % Button pushed function: CoeficientesFIRButton
        function CoeficientesFIRButtonPushed(app, event)
           
            [app.filtrada, app.filtradaFs] = firmanual(app,app.signal, app.sample_rate, app.TamaodelaventanaSpinner.Value);
            graficar(app.UIAxes3_2,'Input signal', app.signal);
            graficar(app.UIAxes_3,'Filtered signal', app.filtrada);
        end

        % Button pushed function: CoeficientesIIRButton
        function CoeficientesIIRButtonPushed(app, event)
            [app.filtrada, app.filtradaFs] = iirmanual(app,app.signal, app.sample_rate);
            graficar(app.UIAxes3_2,'Input signal', app.signal);
            graficar(app.UIAxes_3,'Filtered signal', app.filtrada);

        end

        % Button pushed function: ReproducirsealdeentradaButton
        function ReproducirsealdeentradaButtonPushed(app, event)
          sound(app.signal, app.sample_rate);

        end

        % Value changed function: SealdeentradaDropDown
        function SealdeentradaDropDownValueChanged(app, event)
            [app.signal, app.sample_rate] = drop_down_value_signal(app.SealdeentradaDropDown.Items, app.SealdeentradaDropDown.Value, app.signalsData);

            
        end

        % Value changed function: MododenormalizacinDropDown
        function MododenormalizacinDropDownValueChanged(app, event)
            value = app.MododenormalizacinDropDown.Value;
            app.x_in = preprocesamiento(app.x_in, value);
        end

        % Button pushed function: NormalizeButton
        function NormalizeButtonPushed(app, event)
            graficar(app.UIAxes, 'Normalized signal', app.x_in);
        end

        % Value changed function: Sealdeentrada1DropDown
        function Sealdeentrada1DropDownValueChanged(app, event)
            [app.signal_1, app.sample_rate_1] = drop_down_value_signal(app.Sealdeentrada1DropDown.Items, app.Sealdeentrada1DropDown.Value, app.signalsData);

            graficar(app.UIAxes3_3, 'Signal 1', app.signal_1);

            app.HzEditField_4.Value = num2str(app.sample_rate_1);
            app.sEditField.Value = num2str(0);
        end

        % Value changed function: Sealdeentrada2DropDown
        function Sealdeentrada2DropDownValueChanged(app, event)
            [app.signal_2, app.sample_rate_2] = drop_down_value_signal(app.Sealdeentrada2DropDown.Items, app.Sealdeentrada2DropDown.Value, app.signalsData);

            graficar(app.UIAxes3, 'Signal 2', app.signal_2);

            app.HzEditField.Value = num2str(app.sample_rate_2);
            
            app.sEditField_2.Value = num2str(0);
        end

        % Button down function: UIAxes3_3
        function UIAxes3_3ButtonDown(app, event)
            
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
            
        end

        % Value changed function: HzEditField_4
        function HzEditField_4ValueChanged(app, event)
            value = app.HzEditField_4.Value;
            
        end

        % Value changed function: HzEditField
        function HzEditFieldValueChanged(app, event)
            value = app.HzEditField.Value;
            
        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
            selectedButton = app.ButtonGroup2.SelectedObject;
            modoSeleccionado = selectedButton.Text;
            [app.signal_3, app.sample_rate_3] = Ajustevolumen(app.signal_1,app.sample_rate_1,app.VolumeSlider.Value,modoSeleccionado);
            graficar(app.UIAxes4,'Adjusted volume', app.signal_3);
            
        end

        % Value changed function: sEditField
        function sEditFieldValueChanged(app, event)
            value = app.sEditField.Value;
            
        end

        % Value changed function: sEditField_2
        function sEditField_2ValueChanged(app, event)
            value = app.sEditField_2.Value;
            
            
        end

        % Button pushed function: ModificarButton
        function ModificarButtonPushed(app, event)
            % disp(['signal 1 ', num2str(app.signal_1)]);
            % disp(['sample rate ', num2str(app.sample_rate_1)]);
            % disp(['desired sample rate ', num2str(app.HzEditField_4.Value)]);
            % disp(['desired delay ', num2str(app.sEditField.Value)]);
            % disp(['dimension ', num2str(size(app.signal_1))]);
            [app.signal_1, app.sample_rate_1] = editar_funciones_generadas(app.signal_1, app.sample_rate_1, app.HzEditField_4.Value, app.sEditField.Value);
            app.signal_1 = flip_signal(app.signal_1, app.Switch.Value);
            % disp(['Type of flipped signal ', num2str(class(app.signal_1))]);
            graficar(app.UIAxes3_3, 'Signal 1', app.signal_1);
        end

        % Button pushed function: ModificarButton_2
        function ModificarButton_2Pushed(app, event)
            [app.signal_2, app.sample_rate_2] = editar_funciones_generadas(app.signal_2, app.sample_rate_2, app.HzEditField.Value, app.sEditField_2.Value);
            app.signal_2 = flip_signal(app.signal_2, app.Switch_2.Value);
            graficar(app.UIAxes3, 'Signal 2', app.signal_2);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;

        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            
        end

        % Button pushed function: SumButton
        function SumButtonPushed(app, event)
            % [app.signal_3, app.sample_rate_3] = adicionButtonPushed(app.sample_rate_1, app.sample_rate_2, app.signal_1, app.signal_2);
            [app.signal_3, app.sample_rate_3] = operacion_entre_signals('Suma', app.signal_1, app.sample_rate_1, app.signal_2, app.sample_rate_2);
            graficar(app.UIAxes4, 'Summed signal', app.signal_3);
            % sound(app.signal_3, app.sample_rate_3);
        end

        % Value changed function: EcoButton
        function EcoButtonValueChanged(app, event)
            value = app.EcoButton.Value;
            disp(['Type of flipped signal ', num2str(class(app.signal_1))]);
            disp(['Size of the signal ', num2str(size(app.signal_1))]);
            [app.signal_1, app.sample_rate_1] = eco(app.signal_1, app.sample_rate_1);
            graficar(app.UIAxes4, 'Eco signal', app.signal_1);
        end

        % Button down function: UIAxes4
        function UIAxes4ButtonDown(app, event)
            
        end

        % Button pushed function: Play1Button
        function Play1ButtonPushed(app, event)
            sound(app.signal_1, app.sample_rate_1);
        end

        % Button pushed function: Play2Button
        function Play2ButtonPushed(app, event)
            sound(app.signal_2, app.sample_rate_2);
        end

        % Button pushed function: ProcessedsignalButton
        function ProcessedsignalButtonPushed(app, event)
            sound(app.signal_3, app.sample_rate_3);
        end

        % Button pushed function: MultiplyButton
        function MultiplyButtonPushed(app, event)
            [app.signal_3, app.sample_rate_3] = operacion_entre_signals('Multiplicación', app.signal_1, app.sample_rate_1, app.signal_2, app.sample_rate_2);
            graficar(app.UIAxes4, 'Multiplied signal', app.signal_3);
        end

        % Button pushed function: EstreoButton
        function EstreoButtonPushed(app, event)
            [app.signal_3, app.sample_rate_3] = monoestereo   (app.signal_1, app.sample_rate_1, app.signal_2, app.sample_rate_2);
            graficar (app.UIAxes4,'Estereo Signal',app.signal_3);
        end

        % Button pushed function: MonoButton
        function MonoButtonPushed(app, event)
            [app.signal_3, app.sample_rate_3] = estereomono   (app.signal_1,app.sample_rate_1, app.signal_2, app.sample_rate_2);
            graficar (app.UIAxes4,'Mono Signal',app.signal_3);
        end

        % Button pushed function: ReproducirsealfiltradaButton
        function ReproducirsealfiltradaButtonPushed(app, event)
            sound(app.filtrada,app.filtradaFs);
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
        end

        % Value changed function: SealesDropDown
        function SealesDropDownValueChanged(app, event)
            value = app.SealesDropDown.Value;
            [app.DFTsignalin, app.DFTsampleratein] = drop_down_value_signal(app.SealdeentradaDropDown.Items, app.SealesDropDown.Value, app.signalsData);

        end

        % Button pushed function: DAQButton
        function DAQButtonPushed(app, event)
            % [app.sample_rate_DAQ, app.time_DAQ, app.signal_DAQ] = daq(3000);
            % graficar(app.UIAxes, 'DAQ', app.signal_DAQ);
        end

        % Button pushed function: AplicarDFTButton
        function AplicarDFTButtonPushed(app, event)
            [app.magnitudein,app.phasein] = transformada_fourier(app.DFTsignalin,app.DFTsampleratein,app.UIAxes5,app.UIAxes6);
        end

        % Value changed function: TamaodelaventanaSpinner
        function TamaodelaventanaSpinnerValueChanged(app, event)
            value = app.TamaodelaventanaSpinner.Value;
            
        end

        % Value changed function: SistemasDropDown
        function SistemasDropDownValueChanged(app, event)
            value = app.SistemasDropDown.Value;
            % [app.DFTsignalin, app.DFTsampleratein] = drop_down_value_signal(app.SealdeentradaDropDown.Items, app.SealesDropDown.Value, app.signalsData);

        end

        % Button pushed function: AplicarDFTButton_2
        function AplicarDFTButton_2Pushed(app, event)
            fourier_sistemas(app.SistemasDropDown.Value, 1000, app.UIAxes5_3, app.UIAxes5_2);
        end

        % Button down function: UIAxes5
        function UIAxes5ButtonDown(app, event)
            
        end

        % Button down function: UIAxes5_3
        function UIAxes5_3ButtonDown(app, event)
            
        end

        % Button down function: UIAxes5_2
        function UIAxes5_2ButtonDown(app, event)
            
        end

        % Button down function: UIAxes6
        function UIAxes6ButtonDown(app, event)
            
        end

        % Button pushed function: SpectrumButton
        function SpectrumButtonPushed(app, event)
            espectrograma(app.signal_1, app.sample_rate_1, app.UIAxes7);
        end

        % Button down function: UIAxes7
        function UIAxes7ButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CaptureTab = uitab(app.TabGroup);
            app.CaptureTab.Title = 'Capture';
            app.UIAxes = uiaxes(app.CaptureTab);
            title(app.UIAxes, 'Selected signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.CapturasealPanel = uipanel(app.CaptureTab);
            app.CapturasealPanel.Title = 'Captura señal';
            app.GrabarButton = uibutton(app.CapturasealPanel, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Text = 'Grabar';
            app.GuardarButton = uibutton(app.CapturasealPanel, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.DAQButton = uibutton(app.CapturasealPanel, 'push');
            app.DAQButton.ButtonPushedFcn = createCallbackFcn(app, @DAQButtonPushed, true);
            app.DAQButton.Text = 'DAQ';
            app.GenerarsealPanel = uipanel(app.CaptureTab);
            app.GenerarsealPanel.Title = 'Generar señal';
            app.TipodesealDropDownLabel = uilabel(app.GenerarsealPanel);
            app.TipodesealDropDownLabel.Text = 'Tipo de señal';
            app.TipodesealDropDown = uidropdown(app.GenerarsealPanel);
            app.TipodesealDropDown.Items = {'---', 'Chirp', 'Seno', 'Coseno', 'Diente de sierra', 'Rampa', 'Escalón'};
            app.TipodesealDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodesealDropDownValueChanged, true);
            app.TipodesealDropDown.Value = '---';
            app.GenerarButton = uibutton(app.GenerarsealPanel, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.ImportarsealPanel = uipanel(app.CaptureTab);
            app.ImportarsealPanel.Title = 'Importar señal';
            app.ImportButton = uibutton(app.ImportarsealPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.PlayButton = uibutton(app.CaptureTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.ListadesealesPanel = uipanel(app.CaptureTab);
            app.ListadesealesPanel.Title = 'Lista de señales';
            app.ListBoxLabel = uilabel(app.ListadesealesPanel);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.ListadesealesPanel);
            app.ListBox.Items = {''};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = '';
            app.MododenormalizacinDropDownLabel = uilabel(app.CaptureTab);
            app.MododenormalizacinDropDownLabel.Text = 'Modo de normalización';
            app.MododenormalizacinDropDown = uidropdown(app.CaptureTab);
            app.MododenormalizacinDropDown.Items = {'-------', 'Between 0 and 1', 'Between -1 y 1', 'Standard normalization'};
            app.MododenormalizacinDropDown.ValueChangedFcn = createCallbackFcn(app, @MododenormalizacinDropDownValueChanged, true);
            app.MododenormalizacinDropDown.Value = '-------';
            app.NormalizeButton = uibutton(app.CaptureTab, 'push');
            app.NormalizeButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizeButtonPushed, true);
            app.NormalizeButton.Text = 'Normalize';
            app.FiltradoTab = uitab(app.TabGroup);
            app.FiltradoTab.Title = 'Filtrado';
            app.GraficosdelasealPanel = uipanel(app.FiltradoTab);
            app.GraficosdelasealPanel.Title = 'Graficos de la señal';
            app.UIAxes_3 = uiaxes(app.GraficosdelasealPanel);
            title(app.UIAxes_3, 'Señal filtrada')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes3_2 = uiaxes(app.GraficosdelasealPanel);
            title(app.UIAxes3_2, 'Señal de entrada')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.SeleccindelfiltroPanel = uipanel(app.FiltradoTab);
            app.SeleccindelfiltroPanel.Title = 'Selección del filtro';
            app.FiltrarButton = uibutton(app.SeleccindelfiltroPanel, 'push');
            app.FiltrarButton.ButtonPushedFcn = createCallbackFcn(app, @FiltrarButtonPushed, true);
            app.FiltrarButton.Text = 'Filtrar';
            app.FilterButtonGroup = uibuttongroup(app.SeleccindelfiltroPanel);
            app.FilterButtonGroup.Title = 'Filter';
            app.FIR1Button = uitogglebutton(app.FilterButtonGroup);
            app.FIR1Button.Text = 'FIR1';
            app.FIR1Button.Value = true;
            app.FIR2Button = uitogglebutton(app.FilterButtonGroup);
            app.FIR2Button.Text = 'FIR2';
            app.FIR3Button = uitogglebutton(app.FilterButtonGroup);
            app.FIR3Button.Text = 'FIR3';
            app.FIR4Button = uitogglebutton(app.FilterButtonGroup);
            app.FIR4Button.Text = 'FIR4';
            app.IIR1Button = uitogglebutton(app.FilterButtonGroup);
            app.IIR1Button.Text = 'IIR1';
            app.IIR2Button = uitogglebutton(app.FilterButtonGroup);
            app.IIR2Button.Text = 'IIR2';
            app.IIR3Button = uitogglebutton(app.FilterButtonGroup);
            app.IIR3Button.Text = 'IIR3';
            app.IIR4Button = uitogglebutton(app.FilterButtonGroup);
            app.IIR4Button.Text = 'IIR4';
            app.CoeficientesIIRButton = uibutton(app.SeleccindelfiltroPanel, 'push');
            app.CoeficientesIIRButton.ButtonPushedFcn = createCallbackFcn(app, @CoeficientesIIRButtonPushed, true);
            app.CoeficientesIIRButton.Text = 'Coeficientes IIR';
            app.CoeficientesFIRButton = uibutton(app.SeleccindelfiltroPanel, 'push');
            app.CoeficientesFIRButton.ButtonPushedFcn = createCallbackFcn(app, @CoeficientesFIRButtonPushed, true);
            app.CoeficientesFIRButton.Text = 'Coeficientes FIR';
            app.SealdeentradaDropDownLabel = uilabel(app.SeleccindelfiltroPanel);
            app.SealdeentradaDropDownLabel.Text = 'Señal de entrada';
            app.SealdeentradaDropDown = uidropdown(app.SeleccindelfiltroPanel);
            app.SealdeentradaDropDown.Items = {};
            app.SealdeentradaDropDown.ValueChangedFcn = createCallbackFcn(app, @SealdeentradaDropDownValueChanged, true);
            app.SealdeentradaDropDown.Value = {};
            app.TamaodelaventanaSpinnerLabel = uilabel(app.SeleccindelfiltroPanel);
            app.TamaodelaventanaSpinnerLabel.Text = 'Tamaño de la ventana';
            app.TamaodelaventanaSpinner = uispinner(app.SeleccindelfiltroPanel);
            app.TamaodelaventanaSpinner.Limits = [1 1000];
            app.TamaodelaventanaSpinner.ValueChangedFcn = createCallbackFcn(app, @TamaodelaventanaSpinnerValueChanged, true);
            app.TamaodelaventanaSpinner.Value = 5;
            app.ReproducirsealdeentradaButton = uibutton(app.SeleccindelfiltroPanel, 'push');
            app.ReproducirsealdeentradaButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirsealdeentradaButtonPushed, true);
            app.ReproducirsealdeentradaButton.Text = 'Reproducir señal de entrada';
            app.ReproducirsealfiltradaButton = uibutton(app.SeleccindelfiltroPanel, 'push');
            app.ReproducirsealfiltradaButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirsealfiltradaButtonPushed, true);
            app.ReproducirsealfiltradaButton.Text = 'Reproducir señal filtrada';
            app.ProcesamientoTab = uitab(app.TabGroup);
            app.ProcesamientoTab.Title = 'Procesamiento';
            app.UIAxes3 = uiaxes(app.ProcesamientoTab);
            title(app.UIAxes3, 'Signal 2')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.UIAxes3_3 = uiaxes(app.ProcesamientoTab);
            title(app.UIAxes3_3, 'Signal 1')
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            zlabel(app.UIAxes3_3, 'Z')
            app.UIAxes3_3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3_3ButtonDown, true);
            app.UIAxes4 = uiaxes(app.ProcesamientoTab);
            title(app.UIAxes4, 'Processed signal')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.ButtonDownFcn = createCallbackFcn(app, @UIAxes4ButtonDown, true);
            app.SeleccindesealesPanel = uipanel(app.ProcesamientoTab);
            app.SeleccindesealesPanel.Title = 'Selección de señales';
            app.Sealdeentrada1DropDownLabel = uilabel(app.SeleccindesealesPanel);
            app.Sealdeentrada1DropDownLabel.Text = 'Señal de entrada 1';
            app.Sealdeentrada1DropDown = uidropdown(app.SeleccindesealesPanel);
            app.Sealdeentrada1DropDown.Items = {'---------'};
            app.Sealdeentrada1DropDown.ValueChangedFcn = createCallbackFcn(app, @Sealdeentrada1DropDownValueChanged, true);
            app.Sealdeentrada1DropDown.Value = '---------';
            app.Sealdeentrada2DropDownLabel = uilabel(app.SeleccindesealesPanel);
            app.Sealdeentrada2DropDownLabel.Text = 'Señal de entrada 2';
            app.Sealdeentrada2DropDown = uidropdown(app.SeleccindesealesPanel);
            app.Sealdeentrada2DropDown.Items = {'----------', ''};
            app.Sealdeentrada2DropDown.ValueChangedFcn = createCallbackFcn(app, @Sealdeentrada2DropDownValueChanged, true);
            app.Sealdeentrada2DropDown.Value = '----------';
            app.OperacionesPanel = uipanel(app.ProcesamientoTab);
            app.OperacionesPanel.Title = 'Operaciones';
            app.MultiplyButton = uibutton(app.OperacionesPanel, 'push');
            app.MultiplyButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplyButtonPushed, true);
            app.MultiplyButton.Text = 'Multiply';
            app.SumButton = uibutton(app.OperacionesPanel, 'push');
            app.SumButton.ButtonPushedFcn = createCallbackFcn(app, @SumButtonPushed, true);
            app.SumButton.Text = 'Sum';
            app.VolumePanel = uipanel(app.ProcesamientoTab);
            app.VolumePanel.Title = 'Volume';
            app.VolumeSliderLabel = uilabel(app.VolumePanel);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.VolumePanel);
            app.ButtonGroup2 = uibuttongroup(app.VolumePanel);
            app.AmplitudconstanteButton = uiradiobutton(app.ButtonGroup2);
            app.AmplitudconstanteButton.Text = 'Amplitud constante';
            app.AmplitudconstanteButton.Value = true;
            app.AmplitudlinealmentecrecienteButton = uiradiobutton(app.ButtonGroup2);
            app.AmplitudlinealmentecrecienteButton.Text = 'Amplitud linealmente creciente';
            app.AmplitudlinealmentedecrecienteButton = uiradiobutton(app.ButtonGroup2);
            app.AmplitudlinealmentedecrecienteButton.Text = 'Amplitud linealmente decreciente';
            app.AplicarButton = uibutton(app.VolumePanel, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Icon = fullfile(pathToMLAPP, 'Image_aplicar.jpg');
            app.AplicarButton.Text = 'Aplicar';
            app.GenerarsealapartirdeunaexistentePanel = uipanel(app.ProcesamientoTab);
            app.GenerarsealapartirdeunaexistentePanel.Title = 'Generar señal a partir de una existente';
            app.MonoButton = uibutton(app.GenerarsealapartirdeunaexistentePanel, 'push');
            app.MonoButton.ButtonPushedFcn = createCallbackFcn(app, @MonoButtonPushed, true);
            app.MonoButton.Text = 'Mono';
            app.EstreoButton = uibutton(app.GenerarsealapartirdeunaexistentePanel, 'push');
            app.EstreoButton.ButtonPushedFcn = createCallbackFcn(app, @EstreoButtonPushed, true);
            app.EstreoButton.Text = 'Estéreo';
            app.CambiodefrecuenciaydesplazamientoentiempoPanel = uipanel(app.ProcesamientoTab);
            app.CambiodefrecuenciaydesplazamientoentiempoPanel.Title = 'Cambio de frecuencia y desplazamiento en tiempo';
            app.ModificarButton = uibutton(app.CambiodefrecuenciaydesplazamientoentiempoPanel, 'push');
            app.ModificarButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarButtonPushed, true);
            app.ModificarButton.Text = 'Modificar';
            app.ModificarButton_2 = uibutton(app.CambiodefrecuenciaydesplazamientoentiempoPanel, 'push');
            app.ModificarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ModificarButton_2Pushed, true);
            app.ModificarButton_2.Text = 'Modificar';
            app.HzEditFieldLabel = uilabel(app.CambiodefrecuenciaydesplazamientoentiempoPanel);
            app.HzEditFieldLabel.Text = 'Hz';
            app.HzEditField = uieditfield(app.CambiodefrecuenciaydesplazamientoentiempoPanel, 'text');
            app.HzEditField.ValueChangedFcn = createCallbackFcn(app, @HzEditFieldValueChanged, true);
            app.sEditField_2Label = uilabel(app.CambiodefrecuenciaydesplazamientoentiempoPanel);
            app.sEditField_2Label.Text = 's';
            app.sEditField_2 = uieditfield(app.CambiodefrecuenciaydesplazamientoentiempoPanel, 'text');
            app.sEditField_2.ValueChangedFcn = createCallbackFcn(app, @sEditField_2ValueChanged, true);
            app.sEditFieldLabel = uilabel(app.CambiodefrecuenciaydesplazamientoentiempoPanel);
            app.sEditFieldLabel.Text = 's';
            app.sEditField = uieditfield(app.CambiodefrecuenciaydesplazamientoentiempoPanel, 'text');
            app.sEditField.ValueChangedFcn = createCallbackFcn(app, @sEditFieldValueChanged, true);
            app.HzEditField_4Label = uilabel(app.CambiodefrecuenciaydesplazamientoentiempoPanel);
            app.HzEditField_4Label.Text = 'Hz';
            app.HzEditField_4 = uieditfield(app.CambiodefrecuenciaydesplazamientoentiempoPanel, 'text');
            app.HzEditField_4.ValueChangedFcn = createCallbackFcn(app, @HzEditField_4ValueChanged, true);
            app.Switch = uiswitch(app.CambiodefrecuenciaydesplazamientoentiempoPanel, 'slider');
            app.Switch.Items = {'Normal', 'Inverse'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Normal';
            app.Switch_2 = uiswitch(app.CambiodefrecuenciaydesplazamientoentiempoPanel, 'slider');
            app.Switch_2.Items = {'Normal', 'Inverse'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Normal';
            app.ProcessedsignalButton = uibutton(app.ProcesamientoTab, 'push');
            app.ProcessedsignalButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessedsignalButtonPushed, true);
            app.ProcessedsignalButton.Text = 'Processed signal';
            app.Play2Button = uibutton(app.ProcesamientoTab, 'push');
            app.Play2Button.ButtonPushedFcn = createCallbackFcn(app, @Play2ButtonPushed, true);
            app.Play2Button.Text = 'Play 2';
            app.Play1Button = uibutton(app.ProcesamientoTab, 'push');
            app.Play1Button.ButtonPushedFcn = createCallbackFcn(app, @Play1ButtonPushed, true);
            app.Play1Button.Text = 'Play 1';
            app.Panel = uipanel(app.ProcesamientoTab);
            app.EcoButton = uibutton(app.Panel, 'state');
            app.EcoButton.ValueChangedFcn = createCallbackFcn(app, @EcoButtonValueChanged, true);
            app.EcoButton.Text = 'Eco';
            app.DominiofrecuencialTab = uitab(app.TabGroup);
            app.DominiofrecuencialTab.Title = 'Dominio frecuencial';
            app.SeleccindesealesPanel_2 = uipanel(app.DominiofrecuencialTab);
            app.SeleccindesealesPanel_2.Title = 'Selección de señales';
            app.SealesDropDownLabel = uilabel(app.SeleccindesealesPanel_2);
            app.SealesDropDownLabel.Text = 'Señales';
            app.SealesDropDown = uidropdown(app.SeleccindesealesPanel_2);
            app.SealesDropDown.Items = {'------', ''};
            app.SealesDropDown.ValueChangedFcn = createCallbackFcn(app, @SealesDropDownValueChanged, true);
            app.SealesDropDown.Value = '------';
            app.SistemasDropDownLabel = uilabel(app.SeleccindesealesPanel_2);
            app.SistemasDropDownLabel.Text = 'Sistemas ';
            app.SistemasDropDown = uidropdown(app.SeleccindesealesPanel_2);
            app.SistemasDropDown.Items = {'FIR1', 'FIR2', 'FIR3', 'FIR4', 'IIR1', 'IIR2', 'IIR3', 'IIR4'};
            app.SistemasDropDown.ValueChangedFcn = createCallbackFcn(app, @SistemasDropDownValueChanged, true);
            app.SistemasDropDown.Value = 'FIR1';
            app.AplicarDFTButton = uibutton(app.SeleccindesealesPanel_2, 'push');
            app.AplicarDFTButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarDFTButtonPushed, true);
            app.AplicarDFTButton.Text = 'Aplicar DFT';
            app.AplicarDFTButton_2 = uibutton(app.SeleccindesealesPanel_2, 'push');
            app.AplicarDFTButton_2.ButtonPushedFcn = createCallbackFcn(app, @AplicarDFTButton_2Pushed, true);
            app.AplicarDFTButton_2.Text = 'Aplicar DFT';
            app.SpectrumButton = uibutton(app.SeleccindesealesPanel_2, 'push');
            app.SpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButtonPushed, true);
            app.SpectrumButton.Text = 'Spectrum';
            app.TabGroup2 = uitabgroup(app.DominiofrecuencialTab);
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.UIAxes5_3 = uiaxes(app.Tab);
            title(app.UIAxes5_3, 'Title')
            xlabel(app.UIAxes5_3, 'X')
            ylabel(app.UIAxes5_3, 'Y')
            zlabel(app.UIAxes5_3, 'Z')
            app.UIAxes5_3.ButtonDownFcn = createCallbackFcn(app, @UIAxes5_3ButtonDown, true);
            app.UIAxes5_2 = uiaxes(app.Tab);
            title(app.UIAxes5_2, 'Title')
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            zlabel(app.UIAxes5_2, 'Z')
            app.UIAxes5_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes5_2ButtonDown, true);
            app.UIAxes6 = uiaxes(app.Tab);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes6.ButtonDownFcn = createCallbackFcn(app, @UIAxes6ButtonDown, true);
            app.UIAxes5 = uiaxes(app.Tab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5.ButtonDownFcn = createCallbackFcn(app, @UIAxes5ButtonDown, true);
            app.SpectrumTab = uitab(app.TabGroup2);
            app.SpectrumTab.Title = 'Spectrum';
            app.UIAxes7 = uiaxes(app.SpectrumTab);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes7.ButtonDownFcn = createCallbackFcn(app, @UIAxes7ButtonDown, true);
            app.UIAxes7_2 = uiaxes(app.SpectrumTab);
            title(app.UIAxes7_2, 'Title')
            xlabel(app.UIAxes7_2, 'X')
            ylabel(app.UIAxes7_2, 'Y')
            zlabel(app.UIAxes7_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end